classdef appconv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        INICIOButton     matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        d15_2            matlab.ui.control.NumericEditField
        d14_2            matlab.ui.control.NumericEditField
        d13_2            matlab.ui.control.NumericEditField
        d12_2            matlab.ui.control.NumericEditField
        d11_2            matlab.ui.control.NumericEditField
        c18_2            matlab.ui.control.NumericEditField
        c17_2            matlab.ui.control.NumericEditField
        c16_2            matlab.ui.control.NumericEditField
        c15_2            matlab.ui.control.NumericEditField
        c14_2            matlab.ui.control.NumericEditField
        c13_2            matlab.ui.control.NumericEditField
        c12_2            matlab.ui.control.NumericEditField
        c11_2            matlab.ui.control.NumericEditField
        CorrelacinLabel  matlab.ui.control.Label
        d15              matlab.ui.control.NumericEditField
        d14              matlab.ui.control.NumericEditField
        d13              matlab.ui.control.NumericEditField
        d12              matlab.ui.control.NumericEditField
        d11              matlab.ui.control.NumericEditField
        ConvolucinLabel  matlab.ui.control.Label
        c18              matlab.ui.control.NumericEditField
        c17              matlab.ui.control.NumericEditField
        c16              matlab.ui.control.NumericEditField
        c15              matlab.ui.control.NumericEditField
        c14              matlab.ui.control.NumericEditField
        c13              matlab.ui.control.NumericEditField
        c12              matlab.ui.control.NumericEditField
        c11              matlab.ui.control.NumericEditField
        Seal2Label       matlab.ui.control.Label
        b17              matlab.ui.control.NumericEditField
        b16              matlab.ui.control.NumericEditField
        b15              matlab.ui.control.NumericEditField
        b14              matlab.ui.control.NumericEditField
        b13              matlab.ui.control.NumericEditField
        b12              matlab.ui.control.NumericEditField
        b11              matlab.ui.control.NumericEditField
        Seal1Label       matlab.ui.control.Label
        a17              matlab.ui.control.NumericEditField
        a16              matlab.ui.control.NumericEditField
        a15              matlab.ui.control.NumericEditField
        a14              matlab.ui.control.NumericEditField
        a13              matlab.ui.control.NumericEditField
        a12              matlab.ui.control.NumericEditField
        a11              matlab.ui.control.NumericEditField
        axes4            matlab.ui.control.UIAxes
        axes3            matlab.ui.control.UIAxes
        axes2            matlab.ui.control.UIAxes
        axes             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global A;
            global B;
            global C;
            global D;
            global Lx;
            global Lh;
            global Ly;
            global indice;
            global aux;
            global n;
            global k;

            A=zeros(1,15);
            B=zeros(1,15);
            C=zeros(1,15);
            D=zeros(1,15);

            Lx=0;
             Lh=0;
             Ly=0;
             indice=0;
             aux=0;
             n=0;
            k=0;
        end

        % Value changed function: a11
        function a11ValueChanged(app, event)
           global A;
            A(1,1) = app.a11.Value;
            
        end

        % Value changed function: a12
        function a12ValueChanged(app, event)
            global A;
            A(1,2) = app.a12.Value;
            
        end

        % Value changed function: a13
        function a13ValueChanged(app, event)
            global A;
            A(1,3) = app.a13.Value;
        end

        % Value changed function: a14
        function a14ValueChanged(app, event)
            global A;
            A(1,4) = app.a14.Value;
        end

        % Value changed function: a15
        function a15ValueChanged(app, event)
            global A;
            A(1,5) = app.a15.Value;
        end

        % Value changed function: a16
        function a16ValueChanged(app, event)
            global A;
            A(1,6) = app.a16.Value;
        end

        % Value changed function: a17
        function a17ValueChanged(app, event)
            global A;
            A(1,7) = app.a17.Value;
        end

        % Value changed function: b11
        function b11ValueChanged(app, event)
            global B;
            B(1,1) = app.b11.Value;
        end

        % Value changed function: b12
        function b12ValueChanged(app, event)
            global B;
            B(1,2) = app.b12.Value;
        end

        % Value changed function: b13
        function b13ValueChanged(app, event)
            global B;
            B(1,3) = app.b13.Value;
        end

        % Value changed function: b15
        function b15ValueChanged(app, event)
            global B;
            B(1,5) = app.b15.Value;
        end

        % Value changed function: b14
        function b14ValueChanged(app, event)
           global B;
            B(1,4) = app.b14.Value;
            
        end

        % Value changed function: b16
        function b16ValueChanged(app, event)
            global B;
            B(1,6) = app.b16.Value;
        end

        % Value changed function: b17
        function b17ValueChanged(app, event)
            global B;
            B(1,7) = app.b17.Value;
        end

        % Button pushed function: INICIOButton
        function INICIOButtonPushed(app, event)
            cla(app.INICIOButton,"reset")
            cla(app.axes,"reset")
            cla(app.axes2,"reset")
            cla(app.axes3,"reset")
            cla(app.axes4,"reset")

            global A;
            global B;
            global C;
            global D;
            global Lx;
            global Lh;
            global Ly;
            global indice;
            global aux;
            global n;
            global k;
            global x;
            global y;
            global z;
            global YData;
           

            x=0:15; %EJE X
            n=0;

            %SEÑAL 1
            stem(app.axes,x,YData=A)

            %SEÑAL 2
            stem(app.axes2,x,YData=B)

            %SECCIÓN CONVOLUCIÓN
            
    % Calcula la correlación discreta de dos vectores x y h
            Lx = length(A);
            Lh = length(B);
           
            B = fliplr(B);
            Ly=Lx+Lh-1;
        
            Y=zeros(1, Ly);
            indice=0;
            aux=0;
        
            for n=1:Ly
                for k=1:Lx
                    indice=n-k+1;
        
                    if indice>0 && indice<=Lh
                        aux=B(indice);
                    else
                        aux=0;
                    end
                    Y(n)=Y(n)+A(k)*aux;          
                end
            end
       
                    
                    stem(app.axes3,x,YData=Y(n))
                    
        
                    %bloque c
                    app.c11.Value=C(1,1);
                    app.c12.Value=C(1,2);
                    app.c13.Value=C(1,3);
                    app.c14.Value=C(1,4);
                    app.c15.Value=C(1,5);
                    app.c16.Value=C(1,6);
                    app.c17.Value=C(1,7);
                    app.c18.Value=C(1,8);
                    %bloque d
                    app.d11.Value=C(1,9);
                    app.d12.Value=C(1,10);
                    app.d13.Value=C(1,11);
                    app.d14.Value=C(1,12);
                    app.d15.Value=C(1,13);

            %SECCIÓN CORRELACIÓN
    n=0;
    Lx=length(A);
    Lh=length(B);

    Ly=Lx+Lh-1;

    Y=zeros(1,Ly);
    indice=0;
    aux=0;

    for n=0:Ly-1
        for k=0:Lx-1
            indice=n-k;

            if indice>=0 && indice<Lh
               aux=B(indice+1);
            else
                aux=0;
            end

            Y(n+1)=Y(n+1)+A(k+1)*aux;
        end
     end
end
            z=Y;
            stem(app.axes4,x,YData=z)

            %bloque c
            app.c11_2.Value=D(1,1);
            app.c12_2.Value=D(1,2);
            app.c13_2.Value=D(1,3);
            app.c14_2.Value=D(1,4);
            app.c15_2.Value=D(1,5);
            app.c16_2.Value=D(1,6);
            app.c17_2.Value=D(1,7);
            app.c18_2.Value=D(1,8);
            %bloque d
            app.d11_2.Value=D(1,9);
            app.d12_2.Value=D(1,10);
            app.d13_2.Value=D(1,11);
            app.d14_2.Value=D(1,12);
            app.d15_2.Value=D(1,13);

        end

        % Button down function: axes2
        function axes2ButtonDown(app, event)
            
        end

        % Button down function: axes3
        function axes3ButtonDown(app, event)
            
        end

        % Button down function: axes4
        function axes4ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Señal 1')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.axes2 = uiaxes(app.UIFigure);
            title(app.axes2, 'Señal 2')
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            zlabel(app.axes2, 'Z')
            app.axes2.ButtonDownFcn = createCallbackFcn(app, @axes2ButtonDown, true);
            app.axes3 = uiaxes(app.UIFigure);
            title(app.axes3, 'Convolución')
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            zlabel(app.axes3, 'Z')
            app.axes3.ButtonDownFcn = createCallbackFcn(app, @axes3ButtonDown, true);
            app.axes4 = uiaxes(app.UIFigure);
            title(app.axes4, 'Correlación')
            xlabel(app.axes4, 'X')
            ylabel(app.axes4, 'Y')
            zlabel(app.axes4, 'Z')
            app.axes4.ButtonDownFcn = createCallbackFcn(app, @axes4ButtonDown, true);
            app.Panel = uipanel(app.UIFigure);
            app.a11 = uieditfield(app.Panel, 'numeric');
            app.a11.ValueChangedFcn = createCallbackFcn(app, @a11ValueChanged, true);
            app.a12 = uieditfield(app.Panel, 'numeric');
            app.a12.ValueChangedFcn = createCallbackFcn(app, @a12ValueChanged, true);
            app.a13 = uieditfield(app.Panel, 'numeric');
            app.a13.ValueChangedFcn = createCallbackFcn(app, @a13ValueChanged, true);
            app.a14 = uieditfield(app.Panel, 'numeric');
            app.a14.ValueChangedFcn = createCallbackFcn(app, @a14ValueChanged, true);
            app.a15 = uieditfield(app.Panel, 'numeric');
            app.a15.ValueChangedFcn = createCallbackFcn(app, @a15ValueChanged, true);
            app.a16 = uieditfield(app.Panel, 'numeric');
            app.a16.ValueChangedFcn = createCallbackFcn(app, @a16ValueChanged, true);
            app.a17 = uieditfield(app.Panel, 'numeric');
            app.a17.ValueChangedFcn = createCallbackFcn(app, @a17ValueChanged, true);
            app.Seal1Label = uilabel(app.Panel);
            app.Seal1Label.Text = 'Señal 1';
            app.b11 = uieditfield(app.Panel, 'numeric');
            app.b11.ValueChangedFcn = createCallbackFcn(app, @b11ValueChanged, true);
            app.b12 = uieditfield(app.Panel, 'numeric');
            app.b12.ValueChangedFcn = createCallbackFcn(app, @b12ValueChanged, true);
            app.b13 = uieditfield(app.Panel, 'numeric');
            app.b13.ValueChangedFcn = createCallbackFcn(app, @b13ValueChanged, true);
            app.b14 = uieditfield(app.Panel, 'numeric');
            app.b14.ValueChangedFcn = createCallbackFcn(app, @b14ValueChanged, true);
            app.b15 = uieditfield(app.Panel, 'numeric');
            app.b15.ValueChangedFcn = createCallbackFcn(app, @b15ValueChanged, true);
            app.b16 = uieditfield(app.Panel, 'numeric');
            app.b16.ValueChangedFcn = createCallbackFcn(app, @b16ValueChanged, true);
            app.b17 = uieditfield(app.Panel, 'numeric');
            app.b17.ValueChangedFcn = createCallbackFcn(app, @b17ValueChanged, true);
            app.Seal2Label = uilabel(app.Panel);
            app.Seal2Label.Text = 'Señal 2';
            app.c11 = uieditfield(app.Panel, 'numeric');
            app.c12 = uieditfield(app.Panel, 'numeric');
            app.c13 = uieditfield(app.Panel, 'numeric');
            app.c14 = uieditfield(app.Panel, 'numeric');
            app.c15 = uieditfield(app.Panel, 'numeric');
            app.c16 = uieditfield(app.Panel, 'numeric');
            app.c17 = uieditfield(app.Panel, 'numeric');
            app.c18 = uieditfield(app.Panel, 'numeric');
            app.ConvolucinLabel = uilabel(app.Panel);
            app.ConvolucinLabel.Text = 'Convolución';
            app.d11 = uieditfield(app.Panel, 'numeric');
            app.d12 = uieditfield(app.Panel, 'numeric');
            app.d13 = uieditfield(app.Panel, 'numeric');
            app.d14 = uieditfield(app.Panel, 'numeric');
            app.d15 = uieditfield(app.Panel, 'numeric');
            app.CorrelacinLabel = uilabel(app.Panel);
            app.CorrelacinLabel.Text = 'Correlación';
            app.c11_2 = uieditfield(app.Panel, 'numeric');
            app.c12_2 = uieditfield(app.Panel, 'numeric');
            app.c13_2 = uieditfield(app.Panel, 'numeric');
            app.c14_2 = uieditfield(app.Panel, 'numeric');
            app.c15_2 = uieditfield(app.Panel, 'numeric');
            app.c16_2 = uieditfield(app.Panel, 'numeric');
            app.c17_2 = uieditfield(app.Panel, 'numeric');
            app.c18_2 = uieditfield(app.Panel, 'numeric');
            app.d11_2 = uieditfield(app.Panel, 'numeric');
            app.d12_2 = uieditfield(app.Panel, 'numeric');
            app.d13_2 = uieditfield(app.Panel, 'numeric');
            app.d14_2 = uieditfield(app.Panel, 'numeric');
            app.d15_2 = uieditfield(app.Panel, 'numeric');
            app.INICIOButton = uibutton(app.UIFigure, 'push');
            app.INICIOButton.ButtonPushedFcn = createCallbackFcn(app, @INICIOButtonPushed, true);
            app.INICIOButton.Text = 'INICIO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appconv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end