classdef OpcionBpruebasproyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SERIEDEFOURIERLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        bnLabel                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        anLabel                         matlab.ui.control.Label
        a0Label                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        marcarsielintervalosemultiplicaporpiLabel_2  matlab.ui.control.Label
        NumerodeterminosEditField_2     matlab.ui.control.NumericEditField
        NumerodeterminosEditField_2Label  matlab.ui.control.Label
        tLabel_3                        matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        intervalosuperiorEditField_3    matlab.ui.control.NumericEditField
        intevaloinferiorEditField_3     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        ftEditField_3                   matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        ftEditField_2                   matlab.ui.control.EditField
        ftEditField_2Label              matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        tLabel_2                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        intervalosuperiorEditField_2    matlab.ui.control.NumericEditField
        intevaloinferiorEditField_2     matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        bnLabel_2                       matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        anLabel_2                       matlab.ui.control.Label
        a0Label_2                       matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        CalcularButton_2                matlab.ui.control.Button
        NumerodeterminosEditField       matlab.ui.control.NumericEditField
        NumerodeterminosEditFieldLabel  matlab.ui.control.Label
        marcarsielintervalosemultiplicaporpiLabel  matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        intervalosuperiorEditField      matlab.ui.control.NumericEditField
        intevaloinferiorEditField       matlab.ui.control.NumericEditField
        ftEditField                     matlab.ui.control.EditField
        ftEditFieldLabel                matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
          cla(app.UIAxes2);
          cla(app.UIAxes2_2);
          global c
          global d
          global e
          global f
          ii=app.intevaloinferiorEditField_2.Value;
          is=app.intervalosuperiorEditField_2.Value;
          iii=app.intevaloinferiorEditField_3.Value;
          iss=app.intervalosuperiorEditField_3.Value;
          nt = app.NumerodeterminosEditField_2.Value;
          i=ii*c;
          s=is*d;
          ei=iii*e;
          fs=iss*d;
          aa = i;%0
          bb = s;
          cc = ei;
          dd = fs;%0
          syms n t
          f=app.ftEditField_2.Value;
          tsym = str2sym(f);
          ff=app.ftEditField_3.Value;
          ttsym = str2sym(ff);
          T=s-ei;
          w0=2*pi/T;
          a0=(1/T)*(int(ttsym,t,ei,fs)+int(tsym,t,i,s));
          an=(2/T)*(int(ttsym*cos(n*w0*t),t,ei,fs)+int(tsym*cos(n*w0*t),t,i,s));
          bn=(2/T)*(int(ttsym*sin(n*w0*t),t,ei,fs)+int(tsym*sin(n*w0*t),t,i,s));
          tt=-4*pi:0.01:4*pi;
          ftn=zeros(1,length(tt));
          ftnn=zeros(1,length(tt));
    
          %GRAFICA IDEAL
            time1 = cc:0.01:dd;
            time2 = aa:0.01:bb;
            y1 = double(subs(-tsym,time1));
            y2 = double(subs(-ttsym,time2));
            
            inicio1 = cc - (-cc+dd) - (-c+dd) - (-cc+dd) - (-cc+dd) - (-cc+dd);
            final1 = dd - (-cc+dd) - (-cc+dd) - (-cc+dd) - (-cc+dd) - (-cc+dd);
            
            inicio2 = aa - (aa+bb) - (aa+bb) - (aa+bb) - (aa+bb) - (aa+bb);
            final2 = bb - (aa+bb) - (aa+bb) - (aa+bb) - (aa+bb) - (aa+bb);
            
            for i=-2:7
                 inicio1 = inicio1 + (-cc+dd)+(aa+bb);
                 final1 = final1 + (-cc+dd)+(aa+bb);
                 inicio2 = inicio2 + (aa+bb)+(-cc+dd);
                 final2 = final2 + (aa+bb)+(-cc+dd);
                 time1 = inicio1:0.01:final1;
                 time2 = inicio2:0.01:final2;
                 plot(app.UIAxes2_2,time1,y1,'b')
                 hold(app.UIAxes2_2, 'on');
                 
                 plot(app.UIAxes2_2,time2,y2,'b')
            end

            xlim(app.UIAxes2_2, [-4.2*pi, 4.2*pi]);
          % for mn=1:nt
          %     for m=1:mn
          %         ftnn=ftnn+double(subs(an,n,m)*cos(m*w0*tt))+double(subs(bn,n,m)*sin(m*w0*tt));
          %     end
          %     ftnn=ftnn+double(a0);
          %     plot(app.UIAxes2_2,tt,ftnn)
          %     hold(app.UIAxes2_2, 'on');
          %     app.UIAxes2_2.Title.String=['Aproximación con ',num2str(mn),' terminos'];
          %     pause(0.1)
          % 
          % end
          for m=1:nt
              ftn=ftn+double(subs(an,n,m)*cos(m*w0*tt))+double(subs(bn,n,m)*sin(m*w0*tt));
          end
          ftn=ftn+double(a0);
          plot(app.UIAxes2,tt,ftn)
          app.UIAxes2.Title.String=['Aproximación con ',num2str(nt),' terminos'];
          
          
          app.Label_8.Text=string(a0);
          app.Label_9.Text=string(an);
          app.Label_10.Text=string(bn);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
             global a
            if value==1
                a=pi;
            else
                a=1;
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            global b
            if value==1
                b=pi;
            else
                b=1;
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
             global c
            if value==1
                c=pi;
            else
                c=1;
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
             global d
            if value==1
                d=pi;
            else
                d=1;
            end
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes3_2);
            global a
            global b
            ii = app.intevaloinferiorEditField.Value;
            is = app.intervalosuperiorEditField.Value;
            nt = app.NumerodeterminosEditField.Value;
            i=ii*a;
            s=is*b;
            syms n t
            f=app.ftEditField.Value;
            tsym = str2sym(f);
            
           
            % Pasar a vector
            %tvec = sym2poly(tsym);
            T=s-i;
            w0=2*pi/T;
            a0=(1/T)*(int(tsym,t,i,s));
            an=(2/T)*(int(tsym*cos(n*w0*t),t,i,s));
            bn=(2/T)*(int(tsym*sin(n*w0*t),t,i,s));
            %numTerminos=10;
            tt=-4*pi:0.01:4*pi;
            ftn=zeros(1,length(tt));
            ftnm=zeros(1,length(tt));
            
            % %Grafica ideal
            
            % Límites de la función
            
            time = i:0.001:s;
            y = double(subs(tsym,time));
            
            inicio = i - (s-i) - (s-i) - (s-i);
            final = s - (s-i) - (s-i) - (s-i) ;
            for con=-2:2
                 inicio = inicio + (s-i);
                 final = final + (s-i);
                 time = inicio:0.001:final;
                 plot(app.UIAxes3_2,time,y,'b')
                 hold(app.UIAxes3_2, 'on');
            end
            
            xlim(app.UIAxes3_2, [-4.2*pi, 4.2*pi]);

            %for l=1:nt
            nt1=1;
            % while nt1<=nt
            %     for mm=1:nt1
            %         ftnm=ftnm+double(subs(an,n,mm)*cos(mm*w0*tt))+double(subs(bn,n,mm)*sin(mm*w0*tt));
            %     end
            %     ftnm=ftnm+double(a0);
            %     plot(app.UIAxes3_2,tt,ftnm)
            %     hold(app.UIAxes3_2, 'on');
            %     app.UIAxes3_2.Title.String=['Aproximación con ',num2str(nt1),' terminos'];
            % 
            %     pause(0.1)
            %     nt1=nt1+1;
            % end
            for m=1:nt
                ftn=ftn+double(subs(an,n,m)*cos(m*w0*tt))+double(subs(bn,n,m)*sin(m*w0*tt));
                
            end
            
            ftn=ftn+double(a0);
            plot(app.UIAxes3,tt,ftn)
            app.UIAxes3.Title.String=['Aproximación con ',num2str(nt),' terminos'];
            app.Label_11.Text=string(a0);
            app.Label_12.Text=string(an);
            app.Label_13.Text=string(bn);
            %xlim(app.UIAxes3,[-4*pi 4*pi])
            %app.UIAxes3.XLim = [-4*pi 4*pi];


        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
             global e
            if value==1
                e=pi;
            else
                e=1;
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
             global f
            if value==1
                f=pi;
            else
                f=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.ftEditFieldLabel = uilabel(app.Panel);
            app.ftEditFieldLabel.Text = 'f(t)';
            app.ftEditField = uieditfield(app.Panel, 'text');
            app.intevaloinferiorEditField = uieditfield(app.Panel, 'numeric');
            app.intervalosuperiorEditField = uieditfield(app.Panel, 'numeric');
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '*π';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '*π';
            app.tLabel = uilabel(app.Panel);
            app.tLabel.Text = '≤ t ≤';
            app.marcarsielintervalosemultiplicaporpiLabel = uilabel(app.Panel);
            app.marcarsielintervalosemultiplicaporpiLabel.Text = 'marcar si el intervalo se multiplica por pi';
            app.NumerodeterminosEditFieldLabel = uilabel(app.Panel);
            app.NumerodeterminosEditFieldLabel.Text = 'Numero de terminos ';
            app.NumerodeterminosEditField = uieditfield(app.Panel, 'numeric');
            app.CalcularButton_2 = uibutton(app.Panel, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '.';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = '.';
            app.a0Label_2 = uilabel(app.Panel);
            app.a0Label_2.Text = 'a0=';
            app.anLabel_2 = uilabel(app.Panel);
            app.anLabel_2.Text = 'an=';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '.';
            app.bnLabel_2 = uilabel(app.Panel);
            app.bnLabel_2.Text = 'bn=';
            app.Panel_2 = uipanel(app.UIFigure);
            app.intevaloinferiorEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.intervalosuperiorEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.CheckBox_3 = uicheckbox(app.Panel_2);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.Panel_2);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '*π';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '*π';
            app.tLabel_2 = uilabel(app.Panel_2);
            app.tLabel_2.Text = '≤ t ≤';
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ftEditField_2Label = uilabel(app.Panel_2);
            app.ftEditField_2Label.Text = 'f(t)';
            app.ftEditField_2 = uieditfield(app.Panel_2, 'text');
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '.';
            app.ftEditField_3 = uieditfield(app.Panel_2, 'text');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '{';
            app.intevaloinferiorEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.intervalosuperiorEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.CheckBox_5 = uicheckbox(app.Panel_2);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel_2);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '*π';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '*π';
            app.tLabel_3 = uilabel(app.Panel_2);
            app.tLabel_3.Text = '≤ t ≤';
            app.NumerodeterminosEditField_2Label = uilabel(app.Panel_2);
            app.NumerodeterminosEditField_2Label.Text = 'Numero de terminos ';
            app.NumerodeterminosEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.marcarsielintervalosemultiplicaporpiLabel_2 = uilabel(app.Panel_2);
            app.marcarsielintervalosemultiplicaporpiLabel_2.Text = 'marcar si el intervalo se multiplica por pi';
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '.';
            app.a0Label = uilabel(app.Panel_2);
            app.a0Label.Text = 'a0=';
            app.anLabel = uilabel(app.Panel_2);
            app.anLabel.Text = 'an=';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '.';
            app.bnLabel = uilabel(app.Panel_2);
            app.bnLabel.Text = 'bn=';
            app.SERIEDEFOURIERLabel = uilabel(app.UIFigure);
            app.SERIEDEFOURIERLabel.Text = 'SERIE DE FOURIER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpcionBpruebasproyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end